classdef GUIFINALUTM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Manual                  matlab.ui.control.Button
        INITIALIZETENSILE       matlab.ui.control.Button
        STARTTESTButton         matlab.ui.control.Button
        CLOSEcamButton          matlab.ui.control.Button
        STOPMACHINEButton       matlab.ui.control.Button
        CALIBRATIONButton       matlab.ui.control.Button
        INITIALIZETENSILE_2     matlab.ui.control.Button
        TESTCOLORDropDownLabel  matlab.ui.control.Label
        TESTCOLORDropDown       matlab.ui.control.DropDown
        InitialdistanceLabel    matlab.ui.control.Label
        DISTANCE                matlab.ui.control.NumericEditField
        AREAEditFieldLabel      matlab.ui.control.Label
        AREA                    matlab.ui.control.NumericEditField
        SLabel                  matlab.ui.control.Label
        STRESS                  matlab.ui.control.NumericEditField
        MAXloadinkgLabel        matlab.ui.control.Label
        maxLOAD                 matlab.ui.control.NumericEditField
        loadinkgEditFieldLabel  matlab.ui.control.Label
        LOAD                    matlab.ui.control.NumericEditField
        EEditFieldLabel         matlab.ui.control.Label
        YONGSM                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Property % Description
        vid;
        src;
        pointTracker;
        blobAnalysis;
        diff_im ;
        condition = true;
        hplot;
        hText;
        cmd = 'M';  
        s;
        distance;
        frame;
  maxstress;
        D;
        D1;
   Dis;
  Area;
  %write area of sample for stress F/A in mm^2;


color=0; % just a condition to work on black or white test piece,change value to any other +integer to work on black 0 for white
Fmax =0; 
caliberation_factor  =1; 

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Manual
        function ManualPushed(app, event)
           
            fprintf(app.s,'%s','M');
            
        end

        % Button pushed function: STARTTESTButton
        function STARTTESTButtonPushed(app, event)
             
%  fprintf(app.s,'%s',app.cmd);

 delete (instrfindall)
serialportlist('available')
app.s= serial('COM4'); %#ok<SERIAL> 
app.s.BaudRate =115200;
fopen(app.s);

app.vid = videoinput('winvideo', 2, 'Y800_2456x2052'); %(creating vid inputobj(adaptorname, deviceID,vidformat) ...
                                                %if error comes while detecting, go to apps/image aquisition/tools/refresh)

app.src.Brightness = 0;
app.src.Exposure = 0;
app.src.Gain = 0;
app.src.FrameRate = '60.0002';
app.vid.ROIPosition = [0 951 2456 200];
triggerconfig(app.vid, 'manual');
%vid.FramesPerTrigger  = inf'; %camera framerate persec x time for viewing
app.vid.FrameGrabInterval = 2; %time taken to log in the data
% vid.ReturnedColorspace = 'grayscale';
vid_src = getselectedsource(app.vid);


app.pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
app.blobAnalysis = vision.BlobAnalysis('AreaOutputPort', false, ... % Set blob analysis handling
    'CentroidOutputPort', true, ...
    'BoundingBoxOutputPort', true', ...
    'MinimumBlobArea', 100, ...
    'MaximumBlobArea', 1000, ...
    'MaximumCount', 8);

preview(app.vid);

start(app.vid)
trigger(app.vid);
app.vid.LoggingMode = 'memory';
% stop(app.vid);
% delete(app.vid);
subplot(2,2,1); 
h.plot = plot(NaN,NaN,'o'); hold on;
h.meanPlot = plot(NaN,NaN,'-b');
data.X = [];
data.Y = [];
data.Xm = [];
data.Ym = [];
xlabel('strain')
ylabel('stress')
fprintf(app.s,'%s','M'); %initialising manual mode
app.Area = app.AREA.Value;
app.D1 = abs(app.D);
%% loop
while app.condition 

    app.frame = getsnapshot(app.vid);
    app.diff_im =  imsubtract(app.frame(:,:,1), rgb2gray(app.frame));%
    newframe = im2bw(app.frame);
    if app.color == 0
        newframe1 = imcomplement(newframe);
    else
        newframe1=newframe;
    end
    [centroid, bbox] = app.blobAnalysis(newframe1);
    centroid = double(centroid); % Convert the centroids into Integer for further steps
    
    app.frame = insertShape(app.frame,'Rectangle', bbox, 'Color', 'green');
    subplot(2,1,2),imshow(app.frame);
    numblobs = size(bbox, 1);
    
    if numblobs>=1
        axis on;
        hold on;
        caption = sprintf('%d blobs found in frame', numblobs);
        title(caption, 'FontSize', 10);
        for i = 1 :numblobs
            cX = centroid(i,1); %x-coordinates of the points
            cY =centroid(i,2); %y-coordinates  of points
            app.hplot= plot(cX,cY,'y+' ,'MarkerSize', 5, 'LineWidth', 2);
            app.hText = text(cX, cY,strcat('X:',num2str(round(cX)),'Y:',num2str(round(cY))));
            for j =1
                %Y(i,j) = [round(cY)]
                X(i,j) =[round(cX)];
            end
        end
        app.Dis = X(1,:) -X(end,:); %finds the toppest point and last point in the image and subtracts to fins the distance
       
        app.distance = (abs(app.Dis)-app.D1)/app.D1;
        hold off
%         drawnow; (not rquired now coz its already used for updating plot
%         in the next step
        
    end
  strain = abs(app.distance); %* app.caliberation_factor;    %abs gives only +values
 out = fscanf(app.s);
F = round(abs(str2double(out)));

if F >=app.Fmax
    app.Fmax = F; 
end
if F < app.Fmax *0.8 && F>0
      app.STRESS.Value = app.maxstress; 
%     msgbox('time to stop');
    
end
app.maxLOAD.Value = app.Fmax;
app.LOAD.Value = F;
  stress = F *9.8/app.Area;
  app.maxstress = app.Fmax*9.8/app.Area;
  app.YONGSM.Value = app.maxstress/strain;

data.X(end+1) = strain;
data.Y(end+1) = stress;

set(h.plot, 'xdata', data.X, 'ydata', data.Y);
if length(data.X) > 50
   data.Xm(end+1) = mean(data.X(end-50:end));
   data.Ym(end+1) = mean(data.Y(end-50:end));
   set(h.meanPlot, 'XData', data.Xm, 'Ydata', data.Ym); 
end
drawnow;

 end
stoppreview(app.vid)
stop(app.vid)
delete(app.vid)
fclose(app.s);
delete(app.s)
clear s

        end

        % Button pushed function: INITIALIZETENSILE
        function INITIALIZETENSILEPushed(app, event)
                      
            fprintf(app.s,'%s','T');
 
        end

        % Button pushed function: CLOSEcamButton
        function CLOSEcamButtonPushed(app, event)
stoppreview(app.vid)
stop(app.vid)
delete(app.vid)
clear videoReader

        end

        % Value changed function: TESTCOLORDropDown
        function TESTCOLORDropDownValueChanged(app, event)
   app.color = app.TESTCOLORDropDown.Value;
        end

        % Button pushed function: STOPMACHINEButton
        function STOPMACHINEButtonPushed(app, event)
            fprintf(app.s,'%s','Stop');
        end

        % Button pushed function: CALIBRATIONButton
        function CALIBRATIONButtonPushed(app, event)
 app.vid = videoinput('winvideo', 2, 'Y800_2456x2052'); %(creating vid inputobj(adaptorname, deviceID,vidformat) ...
                                                %if error comes while detecting, go to apps/image aquisition/tools/refresh)
app.vid.ROIPosition = [0 951 2456 200];
triggerconfig(app.vid, 'manual');
% %vid.FramesPerTrigger  = inf'; %camera framerate persec x time for viewing
app.vid.FrameGrabInterval = 2; %time taken to log in the data

app.pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
app.blobAnalysis = vision.BlobAnalysis('AreaOutputPort', false, ... % Set blob analysis handling
    'CentroidOutputPort', true, ...
    'BoundingBoxOutputPort', true', ...
    'MinimumBlobArea', 100, ...
    'MaximumBlobArea', 1000, ...
    'MaximumCount', 8);

preview(app.vid);
% 
start(app.vid)
trigger(app.vid);
app.vid.LoggingMode = 'memory';

while app.condition 
% 
    app.frame = getsnapshot(app.vid);
    app.diff_im =  imsubtract(app.frame(:,:,1), rgb2gray(app.frame));
    newframe = im2bw(app.frame);
    if app.color == 0
        newframe1 = imcomplement(newframe);
    else
        newframe1=newframe;
    end
    [centroid, bbox] = app.blobAnalysis(newframe1);
    centroid = double(centroid); % Convert the centroids into Integer for further steps
    app.frame = insertShape(app.frame,'Rectangle', bbox, 'Color', 'green');
subplot(2,1,2);
      imshow(app.frame);
    numblobs = size(bbox, 1);
%     
    if numblobs>=1
        axis on;
        hold on;
%         caption = sprintf('%d blobs found in frame', numblobs);
%         title(caption, 'FontSize', 10);
        for i = 1 :numblobs
            cX = centroid(i,1); %x-coordinates of the points
            cY =centroid(i,2); %y-coordinates  of points
            app.hplot= plot(cX,cY,'y+' ,'MarkerSize', 5, 'LineWidth', 2);
            app.hText = text(cX, cY,strcat('X:',num2str(round(cX)),'Y:',num2str(round(cY))));
            for j =1
%                 Y(i,j) = [round(cY)]
                X(i,j) =[round(cX)];
            end
        end
        app.D = X(1,:) -X(end,:); %finds the toppest point and last point in the image and subtracts to fins the distance

        hold off
        drawnow;
    end
     app.DISTANCE.Value= abs(app.D);
end
        end

        % Value changed function: AREA
        function AREAValueChanged(app, event)
            app.Area = app.AREA.Value;
            
        end

        % Value changed function: DISTANCE
        function DISTANCEValueChanged(app, event)
            app.D1 = app.DISTANCE.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Manual = uibutton(app.UIFigure, 'push');
            app.Manual.ButtonPushedFcn = createCallbackFcn(app, @ManualPushed, true);
            app.Manual.Text = 'Manual Mode';
            app.INITIALIZETENSILE = uibutton(app.UIFigure, 'push');
            app.INITIALIZETENSILE.ButtonPushedFcn = createCallbackFcn(app, @INITIALIZETENSILEPushed, true);
            app.INITIALIZETENSILE.Text = 'TENSION';
            app.STARTTESTButton = uibutton(app.UIFigure, 'push');
            app.STARTTESTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTESTButtonPushed, true);
            app.STARTTESTButton.Text = 'START TEST';
            app.CLOSEcamButton = uibutton(app.UIFigure, 'push');
            app.CLOSEcamButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEcamButtonPushed, true);
            app.CLOSEcamButton.Text = 'CLOSE cam';
            app.STOPMACHINEButton = uibutton(app.UIFigure, 'push');
            app.STOPMACHINEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMACHINEButtonPushed, true);
            app.STOPMACHINEButton.Text = 'STOP MACHINE';
            app.CALIBRATIONButton = uibutton(app.UIFigure, 'push');
            app.CALIBRATIONButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATIONButtonPushed, true);
            app.CALIBRATIONButton.Text = 'CALIBRATION';
            app.INITIALIZETENSILE_2 = uibutton(app.UIFigure, 'push');
            app.INITIALIZETENSILE_2.Text = 'COMPRESSION';
            app.TESTCOLORDropDownLabel = uilabel(app.UIFigure);
            app.TESTCOLORDropDownLabel.Text = 'TEST COLOR';
            app.TESTCOLORDropDown = uidropdown(app.UIFigure);
            app.TESTCOLORDropDown.Items = {'WHITE', 'BLACK'};
            app.TESTCOLORDropDown.ItemsData = {'0', '1'};
            app.TESTCOLORDropDown.ValueChangedFcn = createCallbackFcn(app, @TESTCOLORDropDownValueChanged, true);
            app.TESTCOLORDropDown.Value = '0';
            app.InitialdistanceLabel = uilabel(app.UIFigure);
            app.InitialdistanceLabel.Text = 'Initial distance';
            app.DISTANCE = uieditfield(app.UIFigure, 'numeric');
            app.DISTANCE.ValueChangedFcn = createCallbackFcn(app, @DISTANCEValueChanged, true);
            app.AREAEditFieldLabel = uilabel(app.UIFigure);
            app.AREAEditFieldLabel.Text = 'AREA';
            app.AREA = uieditfield(app.UIFigure, 'numeric');
            app.AREA.ValueChangedFcn = createCallbackFcn(app, @AREAValueChanged, true);
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S';
            app.STRESS = uieditfield(app.UIFigure, 'numeric');
            app.MAXloadinkgLabel = uilabel(app.UIFigure);
            app.MAXloadinkgLabel.Text = ' MAX load in kg';
            app.maxLOAD = uieditfield(app.UIFigure, 'numeric');
            app.loadinkgEditFieldLabel = uilabel(app.UIFigure);
            app.loadinkgEditFieldLabel.Text = 'load in kg';
            app.LOAD = uieditfield(app.UIFigure, 'numeric');
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Text = 'E';
            app.YONGSM = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIFINALUTM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end