classdef testexperiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CAMONButton             matlab.ui.control.Button
        CAMOFFButton            matlab.ui.control.Button
        DISTANCEEditFieldLabel  matlab.ui.control.Label
        DISTANCE                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
     
        Property % Description
        vid;
        src;
        pointTracker;
        blobAnalysis;
        diff_im ;
        condition = true;
        hplot;
        hText;
        cmd = 'M';  
        s;
        distance;
        frame;
%         data;
        D;
   Dis=1000;
       Area = 39;
  %write area of sample for stress F/A in mm^2;
D1;

color=0; % just a condition to work on black or white test piece,change value to any other +integer to work on black 0 for white
Fmax =0; 
caliberation_factor  =1; 

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
        end

        % Button pushed function: CAMONButton
        function CAMONButtonPushed(app, event)
            app.vid = videoinput('winvideo', 2, 'Y800_2456x2052'); %(creating vid inputobj(adaptorname, deviceID,vidformat) ...
                                                %if error comes while detecting, go to apps/image aquisition/tools/refresh)
app.vid.ROIPosition = [0 951 2456 200];
triggerconfig(app.vid, 'manual');
% %vid.FramesPerTrigger  = inf'; %camera framerate persec x time for viewing
app.vid.FrameGrabInterval = 2; %time taken to log in the data

app.pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
app.blobAnalysis = vision.BlobAnalysis('AreaOutputPort', false, ... % Set blob analysis handling
    'CentroidOutputPort', true, ...
    'BoundingBoxOutputPort', true', ...
    'MinimumBlobArea', 100, ...
    'MaximumBlobArea', 500, ...
    'MaximumCount', 8);

preview(app.vid);
% 
start(app.vid)
trigger(app.vid);
app.vid.LoggingMode = 'memory';


while app.condition 
% 
    app.frame = getsnapshot(app.vid);
    app.diff_im =  imsubtract(app.frame(:,:,1), rgb2gray(app.frame));%
    newframe = im2bw(app.frame);
    if app.color == 0
        newframe1 = imcomplement(newframe);
    else
        newframe1=newframe;
    end
    [centroid, bbox] = app.blobAnalysis(newframe1);
    centroid = double(centroid); % Convert the centroids into Integer for further steps
    app.frame = insertShape(app.frame,'Rectangle', bbox, 'Color', 'green');
    subplot(2,2,1);
      imshow(app.frame);
    numblobs = size(bbox, 1);
%     
    if numblobs>=1
        axis on;
        hold on;
%         caption = sprintf('%d blobs found in frame', numblobs);
%         title(caption, 'FontSize', 10);
        for i = 1 :numblobs
            cX = centroid(i,1); %x-coordinates of the points
            cY =centroid(i,2); %y-coordinates  of points
            app.hplot= plot(cX,cY,'y+' ,'MarkerSize', 5, 'LineWidth', 2);
            app.hText = text(cX, cY,strcat('X:',num2str(round(cX)),'Y:',num2str(round(cY))));
            for j =1
%                 Y(i,j) = [round(cY)]
                X(i,j) =[round(cX)];
            end
        end
        app.D = X(1,:) -X(end,:); %finds the toppest point and last point in the image and subtracts to fins the distance
% %      app.calEditField.Value = app.D;
        hold off
        drawnow;
    end
    
app.DISTANCE.Value= abs(app.D);
end
% stoppreview(app.vid)
% stop(app.vid)
% delete(app.vid)
% fclose(app.s);
% delete(app.s)
% clear s
% clear videoReader
% close(gcf)
% app.calEditField.Value = app.Dis;
        end

        % Button pushed function: CAMOFFButton
        function CAMOFFButtonPushed(app, event)
  stoppreview(app.vid)
stop(app.vid)
delete(app.vid) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CAMONButton = uibutton(app.UIFigure, 'push');
            app.CAMONButton.ButtonPushedFcn = createCallbackFcn(app, @CAMONButtonPushed, true);
            app.CAMONButton.Text = 'CAM ON';
            app.CAMOFFButton = uibutton(app.UIFigure, 'push');
            app.CAMOFFButton.ButtonPushedFcn = createCallbackFcn(app, @CAMOFFButtonPushed, true);
            app.CAMOFFButton.Text = 'CAM OFF';
            app.DISTANCEEditFieldLabel = uilabel(app.UIFigure);
            app.DISTANCEEditFieldLabel.Text = 'DISTANCE';
            app.DISTANCE = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testexperiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end